classdef demo8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        VoltageTemp_8         matlab.ui.control.NumericEditField
        VoltageTemp_7         matlab.ui.control.NumericEditField
        VoltageTemp_6         matlab.ui.control.NumericEditField
        VoltageTemp_5         matlab.ui.control.NumericEditField
        VoltageTemp_4         matlab.ui.control.NumericEditField
        VoltageTemp_3         matlab.ui.control.NumericEditField
        VoltageTemp_2         matlab.ui.control.NumericEditField
        VoltageTemp_1         matlab.ui.control.NumericEditField
        Lamp_8                matlab.ui.control.Lamp
        Label_53              matlab.ui.control.Label
        Lamp_7                matlab.ui.control.Lamp
        Label_52              matlab.ui.control.Label
        Lamp_6                matlab.ui.control.Lamp
        Label_51              matlab.ui.control.Label
        Lamp_5                matlab.ui.control.Lamp
        Label_50              matlab.ui.control.Label
        Lamp_4                matlab.ui.control.Lamp
        Label_49              matlab.ui.control.Label
        Lamp_3                matlab.ui.control.Lamp
        Label_48              matlab.ui.control.Label
        Lamp_2                matlab.ui.control.Lamp
        Label_47              matlab.ui.control.Label
        Lamp_1                matlab.ui.control.Lamp
        Label_46              matlab.ui.control.Label
        MaxV_8                matlab.ui.control.TextArea
        Label_45              matlab.ui.control.Label
        MaxW_8                matlab.ui.control.Lamp
        MinV_8                matlab.ui.control.TextArea
        Label_44              matlab.ui.control.Label
        MinW_8                matlab.ui.control.Lamp
        MaxV_7                matlab.ui.control.TextArea
        Label_43              matlab.ui.control.Label
        MaxW_7                matlab.ui.control.Lamp
        MinV_7                matlab.ui.control.TextArea
        Label_42              matlab.ui.control.Label
        MinW_7                matlab.ui.control.Lamp
        MaxV_6                matlab.ui.control.TextArea
        Label_41              matlab.ui.control.Label
        MaxW_6                matlab.ui.control.Lamp
        MinV_6                matlab.ui.control.TextArea
        Label_40              matlab.ui.control.Label
        MinW_6                matlab.ui.control.Lamp
        MaxV_5                matlab.ui.control.TextArea
        Label_39              matlab.ui.control.Label
        MaxW_5                matlab.ui.control.Lamp
        MinV_5                matlab.ui.control.TextArea
        Label_38              matlab.ui.control.Label
        MinW_5                matlab.ui.control.Lamp
        MaxV_4                matlab.ui.control.TextArea
        Label_37              matlab.ui.control.Label
        MaxW_4                matlab.ui.control.Lamp
        MinV_4                matlab.ui.control.TextArea
        Label_36              matlab.ui.control.Label
        MinW_4                matlab.ui.control.Lamp
        MaxV_3                matlab.ui.control.TextArea
        Label_35              matlab.ui.control.Label
        MaxW_3                matlab.ui.control.Lamp
        MinV_3                matlab.ui.control.TextArea
        Label_34              matlab.ui.control.Label
        MinW_3                matlab.ui.control.Lamp
        MaxV_2                matlab.ui.control.TextArea
        Label_33              matlab.ui.control.Label
        MaxW_2                matlab.ui.control.Lamp
        MinV_2                matlab.ui.control.TextArea
        Label_32              matlab.ui.control.Label
        MinW_2                matlab.ui.control.Lamp
        MinW_1                matlab.ui.control.Lamp
        MinV_1                matlab.ui.control.TextArea
        MaxW_1                matlab.ui.control.Lamp
        MaxV_1                matlab.ui.control.TextArea
        MinWarnClearButton    matlab.ui.control.Button
        MinWarnConfirmButton  matlab.ui.control.Button
        MinWarningTimes       matlab.ui.control.NumericEditField
        MinWarningVoltage     matlab.ui.control.NumericEditField
        GaugeMinVoltage       matlab.ui.control.SemicircularGauge
        MaxWarningVoltage     matlab.ui.control.NumericEditField
        MaxWarningTimes       matlab.ui.control.NumericEditField
        MaxWarnClearButton    matlab.ui.control.Button
        MaxWarnConfirmButton  matlab.ui.control.Button
        GaugeMaxVoltage       matlab.ui.control.SemicircularGauge
        Image                 matlab.ui.control.Image
        Label_31              matlab.ui.control.Label
        Label_30              matlab.ui.control.Label
        Label_29              matlab.ui.control.Label
        Label_28              matlab.ui.control.Label
        Label_27              matlab.ui.control.Label
        Label_26              matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        SignAcceptLamp        matlab.ui.control.Lamp
        Label_7               matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        OpenButton            matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        SerialOpenLamp        matlab.ui.control.Lamp
        Label_6               matlab.ui.control.Label
        SerialSwitch          matlab.ui.control.Switch
        Label_5               matlab.ui.control.Label
        SerialPortNums        matlab.ui.control.DropDown
        Label_3               matlab.ui.control.Label
        SerialBaud            matlab.ui.control.DropDown
        Label                 matlab.ui.control.Label
        setLabel              matlab.ui.control.Label
        UIAxes_8              matlab.ui.control.UIAxes
        UIAxes_7              matlab.ui.control.UIAxes
        UIAxes_6              matlab.ui.control.UIAxes
        UIAxes_5              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_1              matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        SerialObject
        StopLoop
        MaxWarningVolEdits
        MaxWarningVolTimes
        MinWarningVolEdits
        MinWarningVolTimes
    end

    methods (Access = private)

        function results = Char_to_Str_Double(app)
            str =zeros(1,40);
            Tr = 1;
            ReadforS = 1;
            while(ReadforS == 1)
                str(Tr) = char(fread(app.SerialObject,1,'char')); %读取串口数据，读一个字节
                if(str(Tr) == '@') %读取截至标志位
                    ReadforS = 0;
                    str(Tr) = [];
                    break;
                end
                Tr = Tr + 1;
            end
            Cout = char(str);
            Dout = convertCharsToStrings(Cout);
            results = str2double(Dout) - 2.5;
        end

        function [Max,WarnTimes] = funcMax(~,Temp,Max,Tu,Lamp,Warn,WarnTimes)
            if(Temp > Max) %最大值最小值判断与显示函数
                Max = Temp;
            end
            Tu.Value = string(Max);
            if(Temp >= Warn) %报警判断
                Lamp.Color = [1,0,0];%红色  将指示灯变红
                WarnTimes = WarnTimes + 1;
            end
        end

        function [Min,WarnTimes] =funcMin(~,Temp,Min,Tu,Lamp,Warn,WarnTimes)
            if(Temp < Min)
                Min = Temp;
            end
            Tu.Value = string(Min);
            if(Temp <= Warn) %报警判断
                Lamp.Color = [1,0,0];%红色  将指示灯变红
                WarnTimes = WarnTimes + 1;
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaxWarningVoltage.Value = 0; %最大电压报警值设置
            app.MaxWarningTimes.Value = 0; %最大电压报警次数设置
            app.MaxWarningVolEdits = 0;
            app.MaxWarningVolTimes = 0;
            app.MinWarningVoltage.Value = 0; %最小电压报警值设置
            app.MinWarningTimes.Value = 0; %最大电压报警次数设置
            app.MinWarningVolEdits = 0;
            app.MinWarningVolTimes = 0;
            
            %串口创建
            com_list=serialportlist("all");%找到可以用的串口号
            try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="115200";
            catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end
        end

        % Drop down opening function: SerialPortNums
        function SerialPortNumsDropDownOpening(app, event)
            %串口创建
            com_list=serialportlist("all");%找到可以用的串口号
            try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="115200";
            catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
            if(value=='打开')
                try
                    delete(serialport)%注销系统之前已经打开的串口资源
                    app.SerialObject=serialport (...
                        app.SerialPortNums.Value, ...
                        str2double(app.SerialBaud.Value));
                    configureTerminator(app.SerialObject,"CR/LF");   %Set the terminator to "CR/LF" and write a string of ASCII data.\r\n
                    app.SerialOpenLamp.Color=[0,1,0];%绿色  将指示灯打开
                catch
                    errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                    app.SerialSwitch.Value='关闭';
                    return
                end
            elseif (value=='关闭')
                delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                app.SerialOpenLamp.Color=[1,1,1];%白色  将指示灯关闭
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SignAcceptLamp.Color=[1,1,1];%白色  将信号接收指示灯关闭
            cla(app.UIAxes_1); %清理所有图窗
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            cla(app.UIAxes_8);
            clear app.VoltageTemp_1.Value app.VoltageTemp_2.Value ...
                  app.VoltageTemp_3.Value app.VoltageTemp_4.Value ...
                  app.VoltageTemp_5.Value app.VoltageTemp_6.Value ...
                  app.VoltageTemp_7.Value app.VoltageTemp_8.Value;
            flush(app.SerialObject); %清空串口缓冲区，提升性能
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.SignAcceptLamp.Color=[0,1,0];%绿色  将信号接收指示灯打开
            app.StopLoop = 0; %停止标志位置0
            cla(app.UIAxes_1);  %清理所有图窗
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            cla(app.UIAxes_8);
            clear app.VoltageTemp_1.Value app.VoltageTemp_2.Value ...
                  app.VoltageTemp_3.Value app.VoltageTemp_4.Value ...
                  app.VoltageTemp_5.Value app.VoltageTemp_6.Value ...
                  app.VoltageTemp_7.Value app.VoltageTemp_8.Value;
            flush(app.SerialObject); %清空串口缓冲区，提升性能

            while(app.StopLoop == 0)

                %对需要使用的变量进行内存预分配

                temp0 = [0];    %绘图时X轴元素
                temp1 = [0];    %绘图时Y轴元素，分八路
                temp2 = [0];
                temp3 = [0];
                temp4 = [0];
                temp5 = [0];
                temp6 = [0];
                temp7 = [0];
                temp8 = [0];
                Max1 = -Inf;
                Min1 = Inf;
                Max2 = -Inf;
                Min2 = Inf;
                Max3 = -Inf;
                Min3 = Inf;
                Max4 = -Inf;
                Min4 = Inf;
                Max5 = -Inf;
                Min5 = Inf;
                Max6 = -Inf;
                Min6 = Inf;
                Max7 = -Inf;
                Min7 = Inf;
                Max8 = -Inf;
                Min8 = Inf;
                Xi = 0;         %绘图时控制图窗X轴中心点
                Xt = 3;         %绘图时控制图窗X轴范围
                Xtt = 0.1;
                Yt = 1.5;       %绘图时控制图窗y轴范围
                %%%%定义plot句柄，进行绘图准备
                %%%分别对应八个图窗，即八个电压通路
                %%Xi对应X轴，由0递增，图窗X轴大小为Xi +- Xtt
                %temp对应Y轴，由电压值决定，图窗Y轴大小为temp +- 2

                p1 = plot(app.UIAxes_1,temp0,temp1);
                axis(app.UIAxes_1,[Xi-Xt Xi+Xtt Max1-Yt Max1+Yt]);
                p2 = plot(app.UIAxes_2,temp0,temp2);
                axis(app.UIAxes_2,[Xi-Xt Xi+Xtt Max2-Yt Max2+Yt]);
                p3 = plot(app.UIAxes_3,temp0,temp3);
                axis(app.UIAxes_3,[Xi-Xt Xi+Xtt Max3-Yt Max3+Yt]);
                p4 = plot(app.UIAxes_4,temp0,temp4);
                axis(app.UIAxes_4,[Xi-Xt Xi+Xtt Max4-Yt Max4+Yt]);
                p5 = plot(app.UIAxes_5,temp0,temp5);
                axis(app.UIAxes_5,[Xi-Xt Xi+Xtt Max5-Yt Max5+Yt]);
                p6 = plot(app.UIAxes_6,temp0,temp6);
                axis(app.UIAxes_6,[Xi-Xt Xi+Xtt Max6-Yt Max6+Yt]);
                p7 = plot(app.UIAxes_7,temp0,temp7);
                axis(app.UIAxes_7,[Xi-Xt Xi+Xtt Max7-Yt Max7+Yt]);
                p8 = plot(app.UIAxes_8,temp0,temp8);
                axis(app.UIAxes_8,[Xi-Xt Xi+Xtt Max8-Yt Max8+Yt]);

                for Wx = 1:600
                    temp0(Wx) = Xi; %用于绘图的X元素

                    for i = 1:8
                        init = 1;
                        while(init~='#') %设定读取标志位，判断下位机上传数据中的读取标志位
                            init = fread(app.SerialObject,1,'int8'); %读取串口数据，读一个字节
                        end
                        out = fread(app.SerialObject,1,'char'); %读取数据，由字符串数组转为十进制数字
                        str = char(out);
                        str = convertCharsToStrings(str);
                        t = str2double(str);
                        if t == 1
                            temp1(Wx) = Char_to_Str_Double(app);
                            [Max1,app.MaxWarningVolTimes] = funcMax(app,temp1(Wx),Max1,app.MaxV_1,app.MaxW_1,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min1,app.MinWarningVolTimes] = funcMin(app,temp1(Wx),Min1,app.MinV_1,app.MinW_1,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_1.Color=[0,1,0];
                            app.VoltageTemp_1.Value = temp1(Wx);
                        elseif t == 2
                            temp2(Wx) = Char_to_Str_Double(app);
                            [Max2,app.MaxWarningVolTimes] = funcMax(app,temp2(Wx),Max2,app.MaxV_2,app.MaxW_2,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min2,app.MinWarningVolTimes] = funcMin(app,temp2(Wx),Min2,app.MinV_2,app.MinW_2,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_2.Color=[0,1,0];
                            app.VoltageTemp_2.Value = temp2(Wx);
                        elseif t == 3
                            temp3(Wx) = Char_to_Str_Double(app);
                            [Max3,app.MaxWarningVolTimes] = funcMax(app,temp3(Wx),Max3,app.MaxV_3,app.MaxW_3,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min3,app.MinWarningVolTimes] = funcMin(app,temp3(Wx),Min3,app.MinV_3,app.MinW_3,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_3.Color=[0,1,0];
                            app.VoltageTemp_3.Value = temp3(Wx);
                        elseif t == 4
                            temp4(Wx) = Char_to_Str_Double(app);
                            [Max4,app.MaxWarningVolTimes] = funcMax(app,temp4(Wx),Max4,app.MaxV_4,app.MaxW_4,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min4,app.MinWarningVolTimes] = funcMin(app,temp4(Wx),Min4,app.MinV_4,app.MinW_4,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_4.Color=[0,1,0];
                            app.VoltageTemp_4.Value = temp4(Wx);
                        elseif t == 5
                            temp5(Wx) = Char_to_Str_Double(app);
                            [Max5,app.MaxWarningVolTimes] = funcMax(app,temp5(Wx),Max5,app.MaxV_5,app.MaxW_5,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min5,app.MinWarningVolTimes] = funcMin(app,temp5(Wx),Min5,app.MinV_5,app.MinW_5,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_5.Color=[0,1,0];
                            app.VoltageTemp_5.Value = temp5(Wx);
                        elseif t == 6
                            temp6(Wx) = Char_to_Str_Double(app);
                            [Max6,app.MaxWarningVolTimes] = funcMax(app,temp6(Wx),Max6,app.MaxV_6,app.MaxW_6,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min6,app.MinWarningVolTimes] = funcMin(app,temp6(Wx),Min6,app.MinV_6,app.MinW_6,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_6.Color=[0,1,0];
                            app.VoltageTemp_6.Value = temp6(Wx);
                        elseif t == 7
                            temp7(Wx) = Char_to_Str_Double(app);
                            [Max7,app.MaxWarningVolTimes] = funcMax(app,temp7(Wx),Max7,app.MaxV_7,app.MaxW_7,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min7,app.MinWarningVolTimes] = funcMin(app,temp7(Wx),Min7,app.MinV_7,app.MinW_7,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_7.Color=[0,1,0];
                            app.VoltageTemp_7.Value = temp7(Wx);
                        elseif t == 8
                            temp8(Wx) = Char_to_Str_Double(app);
                            [Max8,app.MaxWarningVolTimes] = funcMax(app,temp8(Wx),Max8,app.MaxV_8,app.MaxW_8,app.MaxWarningVolEdits,app.MaxWarningVolTimes);
                            [Min8,app.MinWarningVolTimes] = funcMin(app,temp8(Wx),Min8,app.MinV_8,app.MinW_8,app.MinWarningVolEdits,app.MinWarningVolTimes);
                            app.Lamp_8.Color=[0,1,0];
                            app.VoltageTemp_8.Value = temp8(Wx);
                        end
                    end

                    for i = 1:8
                        init = 1;
                        while(init~='#') %设定读取标志位，判断下位机上传数据中的读取标志位
                            init = fread(app.SerialObject,1,'int8'); %读取串口数据，读一个字节
                        end
                        out = fread(app.SerialObject,1,'char'); %读取数据，由字符串数组转为十进制数字
                        str = char(out);
                        str = convertCharsToStrings(str);
                        t = str2double(str);
                        if t == 1
                        elseif t == 2
                        elseif t == 3
                        elseif t == 4
                        elseif t == 5
                        elseif t == 6
                        elseif t == 7
                        elseif t == 8
                        end
                    end

                    app.MaxWarningTimes.Value = app.MaxWarningVolTimes;
                    app.MinWarningTimes.Value = app.MinWarningVolTimes;

                    %%调用set函数维护已经定义的plot句柄，实现相对流畅的动态绘图
                    %axis函数用于实时更新图窗的位置

                    set(p1,'XData',temp0,'YData',temp1);
                    axis(app.UIAxes_1,[Xi-Xt Xi+Xtt Min1-Yt Max1+Yt]);
                    set(p2,'XData',temp0,'YData',temp2);
                    axis(app.UIAxes_2,[Xi-Xt Xi+Xtt Min2-Yt Max2+Yt]);
                    set(p3,'XData',temp0,'YData',temp3);
                    axis(app.UIAxes_3,[Xi-Xt Xi+Xtt Min3-Yt Max3+Yt]);
                    set(p4,'XData',temp0,'YData',temp4);
                    axis(app.UIAxes_4,[Xi-Xt Xi+Xtt Min4-Yt Max4+Yt]);
                    set(p5,'XData',temp0,'YData',temp5);
                    axis(app.UIAxes_5,[Xi-Xt Xi+Xtt Min5-Yt Max5+Yt]);
                    set(p6,'XData',temp0,'YData',temp6);
                    axis(app.UIAxes_6,[Xi-Xt Xi+Xtt Min6-Yt Max6+Yt]);
                    set(p7,'XData',temp0,'YData',temp7);
                    axis(app.UIAxes_7,[Xi-Xt Xi+Xtt Min7-Yt Max7+Yt]);
                    set(p8,'XData',temp0,'YData',temp8);
                    axis(app.UIAxes_8,[Xi-Xt Xi+Xtt Min8-Yt Max8+Yt]);
                    drawnow limitrate
                    Xi = Xi + 0.1; %用于控制绘图时X元素和图窗X轴中心点的前进，一次前进0.1，配合延迟函数

                    %%延迟函数，控制图像的前进，循环的速度及循环的中断
                    %延迟函数，延迟0.1S左右，配合Xi的自增函数可以实现1秒绘制10个点，对应图窗的1个1格，实现1秒前进1格的效果

                    pause(0.001);

                    %循环中断函数，需要搭配pause使用，不然无法退出

                    if(app.StopLoop == 1) %判断停止标志位是否为1
                        app.SignAcceptLamp.Color=[1,0,0];   %红色 将信号接收指示灯变红
                        break;
                    end

                end

                %600个点后清理图窗，提升性能
                if(Wx == 600)
                    cla(app.UIAxes_1);
                    cla(app.UIAxes_2);
                    cla(app.UIAxes_3);
                    cla(app.UIAxes_4);
                    cla(app.UIAxes_5);
                    cla(app.UIAxes_6);
                    cla(app.UIAxes_7);
                    cla(app.UIAxes_8);
                    flush(app.SerialObject); %清空串口缓冲区，提升性能
                end

                if(app.StopLoop == 1) %判断停止标志位是否为1
                    app.SignAcceptLamp.Color=[1,0,0];   %红色 将信号接收指示灯变红
                    break;
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopLoop = 1; %按下停止按钮，将停止标志位设置为1
            app.Lamp_1.Color=[1,1,1];
            app.Lamp_2.Color=[1,1,1];
            app.Lamp_3.Color=[1,1,1];
            app.Lamp_4.Color=[1,1,1];
            app.Lamp_5.Color=[1,1,1];
            app.Lamp_6.Color=[1,1,1];
            app.Lamp_7.Color=[1,1,1];
            app.Lamp_8.Color=[1,1,1];
        end

        % Button pushed function: MaxWarnConfirmButton
        function MaxWarnConfirmButtonPushed(app, event)
            app.MaxWarningVolEdits = app.MaxWarningVoltage.Value;
            app.GaugeMaxVoltage.Value = app.MaxWarningVolEdits;
        end

        % Button pushed function: MaxWarnClearButton
        function MaxWarnClearButtonPushed(app, event)
            app.MaxWarningTimes.Value = 0;
            app.MaxWarningVolTimes = 0;
            app.MaxW_1.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_2.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_3.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_4.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_5.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_6.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_7.Color=[0,1,0];%绿色  将指示灯变绿
            app.MaxW_8.Color=[0,1,0];%绿色  将指示灯变绿
        end

        % Button pushed function: MinWarnClearButton
        function MinWarnClearButtonPushed(app, event)
            app.MinWarningTimes.Value = 0;
            app.MinWarningVolTimes = 0;
            app.MinW_1.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_2.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_3.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_4.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_5.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_6.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_7.Color=[0,1,0];%绿色  将指示灯变绿
            app.MinW_8.Color=[0,1,0];%绿色  将指示灯变绿
        end

        % Button pushed function: MinWarnConfirmButton
        function MinWarnConfirmButtonPushed(app, event)
            app.MinWarningVolEdits = app.MinWarningVoltage.Value;
            app.GaugeMinVoltage.Value = app.MinWarningVolEdits;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, '第一路电压数据')
            xlabel(app.UIAxes_1, '时间/秒  t/s')
            ylabel(app.UIAxes_1, '电压/伏  u/v')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '第二路电压数据')
            xlabel(app.UIAxes_2, '时间/秒  t/s')
            ylabel(app.UIAxes_2, '电压/伏  u/v')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '第三路电压数据')
            xlabel(app.UIAxes_3, '时间/秒  t/s')
            ylabel(app.UIAxes_3, '电压/伏  u/v')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '第四路电压数据')
            xlabel(app.UIAxes_4, '时间/秒  t/s')
            ylabel(app.UIAxes_4, '电压/伏  u/v')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '第五路电压数据')
            xlabel(app.UIAxes_5, '时间/秒  t/s')
            ylabel(app.UIAxes_5, '电压/伏  u/v')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '第六路电压数据')
            xlabel(app.UIAxes_6, '时间/秒  t/s')
            ylabel(app.UIAxes_6, '电压/伏  u/v')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, '第七路电压数据')
            xlabel(app.UIAxes_7, '时间/秒  t/s')
            ylabel(app.UIAxes_7, '电压/伏  u/v')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, '第八路电压数据')
            xlabel(app.UIAxes_8, '时间/秒  t/s')
            ylabel(app.UIAxes_8, '电压/伏  u/v')
            app.setLabel = uilabel(app.UIFigure);
            app.setLabel.Text = '串口设置';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '波特率';
            app.SerialBaud = uidropdown(app.UIFigure);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000', '256000'};
            app.SerialBaud.Value = '2400';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '串口号';
            app.SerialPortNums = uidropdown(app.UIFigure);
            app.SerialPortNums.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumsDropDownOpening, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '串口操作';
            app.SerialSwitch = uiswitch(app.UIFigure, 'slider');
            app.SerialSwitch.Items = {'打开', '关闭'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.SerialOpenLamp = uilamp(app.UIFigure);
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = '清除';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = '接收信号';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '停止接收';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.SignAcceptLamp = uilamp(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '最大电压值';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '最小电压值';
            app.Label_26 = uilabel(app.UIFigure);
            app.Label_26.Text = '最大电压报警值';
            app.Label_27 = uilabel(app.UIFigure);
            app.Label_27.Text = {'最大电压报警数'; ''};
            app.Label_28 = uilabel(app.UIFigure);
            app.Label_28.Text = '最大电压值';
            app.Label_29 = uilabel(app.UIFigure);
            app.Label_29.Text = '最小电压报警值';
            app.Label_30 = uilabel(app.UIFigure);
            app.Label_30.Text = '最小电压值';
            app.Label_31 = uilabel(app.UIFigure);
            app.Label_31.Text = '最小电压报警数';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'z2.png');
            app.GaugeMaxVoltage = uigauge(app.UIFigure, 'semicircular');
            app.GaugeMaxVoltage.Limits = [-15 15];
            app.MaxWarnConfirmButton = uibutton(app.UIFigure, 'push');
            app.MaxWarnConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @MaxWarnConfirmButtonPushed, true);
            app.MaxWarnConfirmButton.Text = '设置警报值';
            app.MaxWarnClearButton = uibutton(app.UIFigure, 'push');
            app.MaxWarnClearButton.ButtonPushedFcn = createCallbackFcn(app, @MaxWarnClearButtonPushed, true);
            app.MaxWarnClearButton.Text = '报警数归零';
            app.MaxWarningTimes = uieditfield(app.UIFigure, 'numeric');
            app.MaxWarningTimes.RoundFractionalValues = 'on';
            app.MaxWarningVoltage = uieditfield(app.UIFigure, 'numeric');
            app.GaugeMinVoltage = uigauge(app.UIFigure, 'semicircular');
            app.GaugeMinVoltage.Limits = [-15 15];
            app.MinWarningVoltage = uieditfield(app.UIFigure, 'numeric');
            app.MinWarningTimes = uieditfield(app.UIFigure, 'numeric');
            app.MinWarningTimes.RoundFractionalValues = 'on';
            app.MinWarnConfirmButton = uibutton(app.UIFigure, 'push');
            app.MinWarnConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @MinWarnConfirmButtonPushed, true);
            app.MinWarnConfirmButton.Text = '设置警报值';
            app.MinWarnClearButton = uibutton(app.UIFigure, 'push');
            app.MinWarnClearButton.ButtonPushedFcn = createCallbackFcn(app, @MinWarnClearButtonPushed, true);
            app.MinWarnClearButton.Text = '报警数归零';
            app.MaxV_1 = uitextarea(app.UIFigure);
            app.MaxW_1 = uilamp(app.UIFigure);
            app.MinV_1 = uitextarea(app.UIFigure);
            app.MinW_1 = uilamp(app.UIFigure);
            app.MinW_2 = uilamp(app.UIFigure);
            app.Label_32 = uilabel(app.UIFigure);
            app.Label_32.Text = '最小电压值';
            app.MinV_2 = uitextarea(app.UIFigure);
            app.MaxW_2 = uilamp(app.UIFigure);
            app.Label_33 = uilabel(app.UIFigure);
            app.Label_33.Text = '最大电压值';
            app.MaxV_2 = uitextarea(app.UIFigure);
            app.MinW_3 = uilamp(app.UIFigure);
            app.Label_34 = uilabel(app.UIFigure);
            app.Label_34.Text = '最小电压值';
            app.MinV_3 = uitextarea(app.UIFigure);
            app.MaxW_3 = uilamp(app.UIFigure);
            app.Label_35 = uilabel(app.UIFigure);
            app.Label_35.Text = '最大电压值';
            app.MaxV_3 = uitextarea(app.UIFigure);
            app.MinW_4 = uilamp(app.UIFigure);
            app.Label_36 = uilabel(app.UIFigure);
            app.Label_36.Text = '最小电压值';
            app.MinV_4 = uitextarea(app.UIFigure);
            app.MaxW_4 = uilamp(app.UIFigure);
            app.Label_37 = uilabel(app.UIFigure);
            app.Label_37.Text = '最大电压值';
            app.MaxV_4 = uitextarea(app.UIFigure);
            app.MinW_5 = uilamp(app.UIFigure);
            app.Label_38 = uilabel(app.UIFigure);
            app.Label_38.Text = '最小电压值';
            app.MinV_5 = uitextarea(app.UIFigure);
            app.MaxW_5 = uilamp(app.UIFigure);
            app.Label_39 = uilabel(app.UIFigure);
            app.Label_39.Text = '最大电压值';
            app.MaxV_5 = uitextarea(app.UIFigure);
            app.MinW_6 = uilamp(app.UIFigure);
            app.Label_40 = uilabel(app.UIFigure);
            app.Label_40.Text = '最小电压值';
            app.MinV_6 = uitextarea(app.UIFigure);
            app.MaxW_6 = uilamp(app.UIFigure);
            app.Label_41 = uilabel(app.UIFigure);
            app.Label_41.Text = '最大电压值';
            app.MaxV_6 = uitextarea(app.UIFigure);
            app.MinW_7 = uilamp(app.UIFigure);
            app.Label_42 = uilabel(app.UIFigure);
            app.Label_42.Text = '最小电压值';
            app.MinV_7 = uitextarea(app.UIFigure);
            app.MaxW_7 = uilamp(app.UIFigure);
            app.Label_43 = uilabel(app.UIFigure);
            app.Label_43.Text = '最大电压值';
            app.MaxV_7 = uitextarea(app.UIFigure);
            app.MinW_8 = uilamp(app.UIFigure);
            app.Label_44 = uilabel(app.UIFigure);
            app.Label_44.Text = '最小电压值';
            app.MinV_8 = uitextarea(app.UIFigure);
            app.MaxW_8 = uilamp(app.UIFigure);
            app.Label_45 = uilabel(app.UIFigure);
            app.Label_45.Text = '最大电压值';
            app.MaxV_8 = uitextarea(app.UIFigure);
            app.Label_46 = uilabel(app.UIFigure);
            app.Label_46.Text = '1';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Label_47 = uilabel(app.UIFigure);
            app.Label_47.Text = '2';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_48 = uilabel(app.UIFigure);
            app.Label_48.Text = '3';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Label_49 = uilabel(app.UIFigure);
            app.Label_49.Text = '4';
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Label_50 = uilabel(app.UIFigure);
            app.Label_50.Text = '5';
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Label_51 = uilabel(app.UIFigure);
            app.Label_51.Text = '6';
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Label_52 = uilabel(app.UIFigure);
            app.Label_52.Text = '7';
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Label_53 = uilabel(app.UIFigure);
            app.Label_53.Text = '8';
            app.Lamp_8 = uilamp(app.UIFigure);
            app.VoltageTemp_1 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_2 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_3 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_4 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_5 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_6 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_7 = uieditfield(app.UIFigure, 'numeric');
            app.VoltageTemp_8 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end